classdef FinApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        FinancialAnalyzerUIFigure  matlab.ui.Figure
        TabGroup                   matlab.ui.container.TabGroup
        Tab                        matlab.ui.container.Tab
        UITable                    matlab.ui.control.Table
        Label_2                    matlab.ui.control.Label
        FromDate                   matlab.ui.control.DatePicker
        Label_3                    matlab.ui.control.Label
        ToDate                     matlab.ui.control.DatePicker
        OpenFile                   matlab.ui.control.Button
        Label_10                   matlab.ui.control.Label
        Label_21                   matlab.ui.control.Label
        FilterCat                  matlab.ui.control.DropDown
        FilterName                 matlab.ui.control.DropDown
        Label_24                   matlab.ui.control.Label
        Cards                      matlab.ui.control.ListBox
        Tab_8                      matlab.ui.container.Tab
        AxInout                    matlab.ui.control.UIAxes
        CashIn                     matlab.ui.control.Label
        CashOut                    matlab.ui.control.Label
        Tab_2                      matlab.ui.container.Tab
        TabGroup2                  matlab.ui.container.TabGroup
        Tab_5                      matlab.ui.container.Tab
        AxNames                    matlab.ui.control.UIAxes
        Label_4                    matlab.ui.control.Label
        Category                   matlab.ui.control.DropDown
        Reset                      matlab.ui.control.Button
        AxCats                     matlab.ui.control.UIAxes
        Tab_6                      matlab.ui.container.Tab
        AxIntime                   matlab.ui.control.UIAxes
        Label_11                   matlab.ui.control.Label
        Category2                  matlab.ui.control.DropDown
        Tab_3                      matlab.ui.container.Tab
        Label_5                    matlab.ui.control.Label
        NamesCats                  matlab.ui.control.ListBox
        Label_6                    matlab.ui.control.Label
        RuleType                   matlab.ui.control.DropDown
        PreviewLabel               matlab.ui.control.Label
        Preview                    matlab.ui.control.ListBox
        Label_8                    matlab.ui.control.Label
        NewValue                   matlab.ui.control.DropDown
        Rules                      matlab.ui.control.Table
        Apply                      matlab.ui.control.Button
        Tab_7                      matlab.ui.container.Tab
        Panel                      matlab.ui.container.Panel
        Label_19                   matlab.ui.control.Label
        Label_13                   matlab.ui.control.Label
        DateFormat                 matlab.ui.control.EditField
        Label_15                   matlab.ui.control.Label
        ColDate                    matlab.ui.control.NumericEditField
        Label_16                   matlab.ui.control.Label
        ColSum                     matlab.ui.control.NumericEditField
        Label_17                   matlab.ui.control.Label
        ColCat                     matlab.ui.control.NumericEditField
        Label_18                   matlab.ui.control.Label
        ColName                    matlab.ui.control.NumericEditField
        Label_20                   matlab.ui.control.Label
        LoadFormat                 matlab.ui.control.DropDown
        Label_22                   matlab.ui.control.Label
        ColCard                    matlab.ui.control.NumericEditField
        Label_23                   matlab.ui.control.Label
        ColStatus                  matlab.ui.control.NumericEditField
        Panel_2                    matlab.ui.container.Panel
        Label_12                   matlab.ui.control.Label
        DataPath                   matlab.ui.control.EditField
        Label_14                   matlab.ui.control.Label
        FileMask                   matlab.ui.control.EditField
        AutoLoad                   matlab.ui.control.CheckBox
    end

    
    properties (Access = private)
        T0 % raw data table
        Tp % prettified data table
        T % shown data table
        To % out data table
        fname % excel file
        R % Rules
        selR % Selected rule
    end
    
    methods (Access = private)
        
        function fpath = get_latest(app)
            fs = struct2table(dir(fullfile(app.DataPath.Value, app.FileMask.Value)));
            if ~isempty(fs)
                fs.date = datetime(fs.datenum, 'ConvertFrom', 'datenum');
                fs = sortrows(fs, 'date', 'descend');
                folders = string(fs.folder);
                fnames = string(fs.name);
                fpath = fullfile(folders(1), fnames(1));
            else
                fpath = 0;
            end
        end
        
        function T = recat(~, T, old, new)
            T.Cat = mergecats(T.Cat, old, new);
        end
        
        function T = rename(~, T, old, new)
            T.Name = mergecats(T.Name, old, new);
        end
        
        function T = switchcat(~, T, desc, cat)
            T.Cat(ismember(T.Name, desc)) = cat;
        end
        
        function T = prepare_data(app)
            T = app.T0;
            R = app.R;
            T = T(T.Date >= app.FromDate.Value & T.Date <= (app.ToDate.Value + days(1)), :);
            if ~isempty(R)
                for i = 1 : height(R)
                    switch R.Type{i}
                        case "RC"
                            T = app.recat(T, R.Old{i}, R.New{i});
                        case "RN"
                            T = app.rename(T, R.Old{i}, R.New{i});
                        otherwise % SC
                            T = app.switchcat(T, R.Old{i}, R.New{i});
                    end
                end
            end
            T.Name = removecats(T.Name);
            T.Cat = removecats(T.Cat);
            app.Tp = T;
        end
        
        function T = load_data(app)
            pr = uiprogressdlg(app.FinancialAnalyzerUIFigure, 'Title', 'Чтение файла...', 'Indeterminate', 'on');
            T = readtable(app.fname, 'ReadVariableNames', false);
%             if isempty(app.Cards.Value)
                cards = T{:, app.ColCard.Value};
                [G,ID] = findgroups(cards);
                app.Cards.Items = ID;
                app.Cards.Value = ID{mode(G)};
%             end
            T = T(ismember(T{:, app.ColCard.Value}, string(app.Cards.Value)), :);
            cols = [app.ColDate.Value app.ColStatus.Value app.ColSum.Value app.ColCat.Value app.ColName.Value];
            T = T(:, cols);
            T = T(T{:, 2} == "OK", :);
            T(:, 2) = [];
            T.Properties.VariableNames = {'Date', 'Sum', 'Cat', 'Name'};
            T.Cat = categorical(T.Cat);
            T.Date = datetime(T.Date, 'InputFormat', app.DateFormat.Value);
            T.Name = categorical(T.Name);
            if ismissing(app.FromDate.Value) && ~isempty(T)
                app.FromDate.Value = min(T.Date);
            end
            T.Sum = str2double(T.Sum);
            T.In = T.Sum > 0;
            app.T0 = T;
            app.prepare_data();
            close(pr);
            app.show_data();
        end
        
        function T = show_data(app)
            T = app.Tp;
            app.FilterCat.Items = [{'- Все категории -'}; categories(T.Cat)];
            app.FilterName.Items = [{'- Все названия -'}; categories(T.Name)];
            filtcat = app.FilterCat.Value;
            filtname = app.FilterName.Value;
            if filtcat ~= "- Все категории -"
                T = T(T.Cat == string(filtcat), :);
            end
            if filtname ~= "- Все названия -"
                T = T(T.Name == string(filtname), :);
            end
            app.T = T;
            if ~isempty(T)
                app.UITable.Data = T;
            else
                app.UITable.Data = [];
            end
            app.UITable.ColumnName = T.Properties.VariableNames;
            app.analyze_data();
            app.RuleTypeValueChanged();
        end
        
        function analyze_data(app)
            T = app.Tp;
            if ~isempty(T)
                pr = uiprogressdlg(app.FinancialAnalyzerUIFigure, 'Title', 'Обработка данных...', 'Indeterminate', 'on');
                T1 = groupsummary(T, 'In', 'sum', 'Sum');
                sumin = T1.sum_Sum(2);
                sumout = abs(T1.sum_Sum(1));
                flow = sumin + sumout;
                saldo = sumin - sumout;
                rectangle(app.AxInout, 'Position', [0 0 sumin/flow .5], 'FaceColor', 'g');
                rectangle(app.AxInout, 'Position', [sumin/flow 0 sumout/flow .5], 'FaceColor', 'r');
                title(app.AxInout, "Остаток: " + round(saldo))
                axis(app.AxInout, 'off');
                app.CashIn.Text = "+" + round(sumin);
                app.CashOut.Text = "-" + round(sumout);
                To = T(~T.In, :);
                To.Sum = -To.Sum;
                To.Cat = removecats(To.Cat);
                app.To = To;
                T2 = groupsummary(To, 'Cat', 'sum', 'Sum');
                T2 = sortrows(T2, 'sum_Sum', 'descend');
                app.Category.Items = string(T2.Cat);
                app.Category2.Items = string(T2.Cat);
                h = Vis.bar(app.AxCats, T2, 'sum_Sum', 'textvar', 'Cat');
                h.ButtonDownFcn = @app.clickBar;
                title(app.AxCats, ["Итог: " + round(sum(T2.sum_Sum)); "(кликабельно)"]);
                app.CategoryValueChanged();
                app.Category2ValueChanged();
                close(pr);
            else
                app.AxCats.cla();
                app.AxNames.cla();
            end
        end
        
        function R = load_rules(app)
            fname = 'rules.mat';
            if isfile(fname)
                data = load(fname);
                R = data.R;
            else
                R = cell2table(cell(0, 3), 'VariableNames', ["Type", "Old", "New"]);
            end
            app.R = R;
        end
        
        function R = save_rules(app)
            fname = 'rules.mat';
            R = app.R;
            save(fname, 'R');
        end
        
        function update_rules(app)
            if ~isempty(app.R)
                app.R = sortrows(app.R, 'Type');
                Rvis = app.R;
                Rvis.Old = cellfun(@(x) join(x,','), Rvis.Old);
                app.Rules.Data = Rvis;
                app.Rules.ColumnName = Rvis.Properties.VariableNames;
            else
                app.Rules.Data = {};
            end
        end
        
        function main(app)
            app.ToDate.Value = datetime('now');
            if ~isempty(app.fname)
                [~, fname, fext] = fileparts(app.fname);
                app.Label_10.Text = "Загружен: " + fname + fext;
                app.load_rules();
                app.load_data();
                app.update_rules();
            else
                app.Label_10.Text = 'Откройте файл';
            end
        end
        
        function clickBar(app, b, event)
            p = event.IntersectionPoint;
            x = [b.XData - b.BarWidth/2; b.XData + b.BarWidth/2]';
            num = find(p(1)>=x(:,1) & p(1)<=x(:,2));
            if ~isempty(num)
                cat = b.Parent.XTickLabel{num};
                if app.Category.Value ~= string(cat)
                    app.Category.Value = cat;
                    app.CategoryValueChanged();
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            if app.AutoLoad.Value
                app.fname = app.get_latest();
            end
            app.main();
        end

        % Value changed function: Cards
        function CardValueChanged(app, event)
            app.load_data();
        end

        % Value changed function: Category
        function CategoryValueChanged(app, event)
            CatName = app.Category.Value;
            b = findobj(app.AxCats.Children, 'Type', 'bar');
            b.CData = repmat([0 0.4470 0.7410], length(b.XData), 1);
            b.CData(app.Category.Items == string(CatName), :) = [0.72 0.27 1];
            To = app.To;
            T3 = To(To.Cat == CatName, :);
            T3 = groupsummary(T3, 'Name', 'sum', 'Sum');
            Vis.bar(app.AxNames, T3, 'sum_Sum', 'textvar', 'Name');
            title(app.AxNames, CatName + ": " + round(sum(T3.sum_Sum)));
        end

        % Value changed function: NamesCats
        function NamesCatsValueChanged(app, event)
            value = app.NamesCats.Value;
            if app.RuleType.Value == "RC"
                T = app.T(ismember(app.T.Cat, value), 'Name');
                label = 'Названия';
                items = T.Name;
            else
                T = app.T(ismember(app.T.Name, value), 'Cat');
                label = 'Категории';
                items = T.Cat;
            end
            app.PreviewLabel.Text = label;
            app.Preview.Items = categories(removecats(items));
            app.Apply.Text = '>';
            app.selR = [];
        end

        % Value changed function: RuleType
        function RuleTypeValueChanged(app, event)
            value = app.RuleType.Value;
            if value == "RC"
                label = "Категории";
                labelpr = "Названия";
                items1 = app.T.Cat;
            else
                label = "Названия";
                labelpr = "Категории";
                items1 = app.T.Name;
            end
            if ~isempty(app.R)
                items = [];
                if value == "RC"
                    R = app.R(app.R.Type == "RC", :);
                    if ~isempty(R)
                        items = horzcat(R.Old{:})';
                    end
                    items = [items; R.New];
                    R = app.R(app.R.Type == "SC", :);
                    items = [items; R.New];
                else
                    R = app.R(app.R.Type ~= "RC", :);
                    if ~isempty(R)
                        items = horzcat(R.Old{:})';
                    end
                    R = app.R(app.R.Type == "RN", :);
                    items = [items; R.New];
                end
                items1 = unique([items1; items]);
            end
            if value == "RN"
                items2 = app.T.Name;
            else
                items2 = app.T.Cat;
            end
            app.Label_5.Text = label;
            app.PreviewLabel.Text = labelpr;
            app.NamesCats.Items = sort(categories(items1));
            app.NewValue.Items = sort([{''}; categories(items2)]);
            app.Apply.Text = '>';
            app.selR = [];
        end

        % Button pushed function: Apply
        function ApplyButtonPushed(app, event)
            if app.Apply.Text == ">"
                rule = {app.RuleType.Value, {app.NamesCats.Value}, app.NewValue.Value};
                app.R = [app.R; rule];
            elseif ~isempty(app.selR)
                app.R(app.selR, :) = [];
            end
            app.RuleTypeValueChanged();
            app.update_rules();
            app.save_rules();
            app.prepare_data();
            app.show_data();
        end

        % Cell selection callback: Rules
        function RulesCellSelection(app, event)
            i = event.Indices(1);
            app.RuleType.Value = app.R.Type{i};
            app.RuleTypeValueChanged();
            app.NamesCats.Value = app.R.Old{i};
            app.NewValue.Value = app.R.New{i};
            app.Apply.Text = '<';
            app.selR = i;
        end

        % Button pushed function: OpenFile
        function OpenFileButtonPushed(app, event)
            [file, path] = uigetfile('*.xls;*.xlsx;*.csv');
            if file
                app.fname = fullfile(path, file);
                app.main();
            end
        end

        % Button pushed function: Reset
        function ResetButtonPushed(app, event)
            app.analyze_data();
        end

        % Value changed function: Category2
        function Category2ValueChanged(app, event)
            CatName = app.Category2.Value;
            To = app.To;
            T3 = To(To.Cat == CatName, :);
            T3 = sortrows(T3, 'Date', 'ascend');
            T3.CumSum = cumsum(T3.Sum);
            app.AxIntime.cla();
            app.AxIntime.reset();
            rep = diff(T3.Date)==0;
            T3.Date(rep) = T3.Date(rep) + seconds(0.1);
            Vis.bar(app.AxIntime, T3, 'Sum', 'xvar', 'Date');
            app.AxIntime.NextPlot = 'add';
            plot(app.AxIntime, T3.Date, T3.CumSum, 'LineWidth', 3, 'Marker', '.', 'MarkerSize', 15);
            grid(app.AxIntime, 'on');
            title(app.AxIntime, string(round(T3.CumSum(end))) + " р");
        end

        % Value changed function: FilterCat, FilterName
        function FilterCatValueChanged(app, event)
            app.show_data();
        end

        % Value changed function: FromDate, ToDate
        function FromDateValueChanged(app, event)
            app.prepare_data();
            app.show_data();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.FinancialAnalyzerUIFigure = uifigure('Visible', 'off');
            app.FinancialAnalyzerUIFigure.Name = 'Financial Analyzer';
            app.TabGroup = uitabgroup(app.FinancialAnalyzerUIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = 'Данные';
            app.UITable = uitable(app.Tab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'; ''};
            app.UITable.RowName = {};
            app.Label_2 = uilabel(app.Tab);
            app.Label_2.Text = 'С';
            app.FromDate = uidatepicker(app.Tab);
            app.FromDate.ValueChangedFcn = createCallbackFcn(app, @FromDateValueChanged, true);
            app.Label_3 = uilabel(app.Tab);
            app.Label_3.Text = 'По';
            app.ToDate = uidatepicker(app.Tab);
            app.ToDate.ValueChangedFcn = createCallbackFcn(app, @FromDateValueChanged, true);
            app.ToDate.Value = datetime([2018 9 25]);
            app.OpenFile = uibutton(app.Tab, 'push');
            app.OpenFile.ButtonPushedFcn = createCallbackFcn(app, @OpenFileButtonPushed, true);
            app.OpenFile.Text = 'Открыть файл';
            app.Label_10 = uilabel(app.Tab);
            app.Label_10.Text = 'Загрузка...';
            app.Label_21 = uilabel(app.Tab);
            app.Label_21.Text = 'Фильтр';
            app.FilterCat = uidropdown(app.Tab);
            app.FilterCat.Items = {'- Все категории -'};
            app.FilterCat.ValueChangedFcn = createCallbackFcn(app, @FilterCatValueChanged, true);
            app.FilterCat.Value = '- Все категории -';
            app.FilterName = uidropdown(app.Tab);
            app.FilterName.Items = {'- Все названия -'};
            app.FilterName.ValueChangedFcn = createCallbackFcn(app, @FilterCatValueChanged, true);
            app.FilterName.Value = '- Все названия -';
            app.Label_24 = uilabel(app.Tab);
            app.Label_24.Text = 'Карты';
            app.Cards = uilistbox(app.Tab);
            app.Cards.Items = {};
            app.Cards.Multiselect = 'on';
            app.Cards.ValueChangedFcn = createCallbackFcn(app, @CardValueChanged, true);
            app.Cards.Value = {};
            app.Tab_8 = uitab(app.TabGroup);
            app.Tab_8.Title = 'Обзор';
            app.AxInout = uiaxes(app.Tab_8);
            title(app.AxInout, 'Остаток')
            xlabel(app.AxInout, '')
            ylabel(app.AxInout, '')
            app.AxInout.XTick = [];
            app.AxInout.YTick = [];
            app.CashIn = uilabel(app.Tab_8);
            app.CashIn.Text = 'In';
            app.CashOut = uilabel(app.Tab_8);
            app.CashOut.Text = 'Out';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = 'Расходы';
            app.TabGroup2 = uitabgroup(app.Tab_2);
            app.Tab_5 = uitab(app.TabGroup2);
            app.Tab_5.Title = 'По категориям';
            app.AxNames = uiaxes(app.Tab_5);
            title(app.AxNames, 'Категория')
            app.AxNames.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Label_4 = uilabel(app.Tab_5);
            app.Label_4.Text = 'Категория';
            app.Category = uidropdown(app.Tab_5);
            app.Category.Items = {};
            app.Category.ValueChangedFcn = createCallbackFcn(app, @CategoryValueChanged, true);
            app.Category.Value = {};
            app.Reset = uibutton(app.Tab_5, 'push');
            app.Reset.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.Reset.Text = 'Перестроить';
            app.AxCats = uiaxes(app.Tab_5);
            title(app.AxCats, 'Итог')
            app.AxCats.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.Tab_6 = uitab(app.TabGroup2);
            app.Tab_6.Title = 'Во времени';
            app.AxIntime = uiaxes(app.Tab_6);
            title(app.AxIntime, 'Title')
            xlabel(app.AxIntime, 'X')
            ylabel(app.AxIntime, 'Y')
            app.Label_11 = uilabel(app.Tab_6);
            app.Label_11.Text = 'Категория';
            app.Category2 = uidropdown(app.Tab_6);
            app.Category2.ValueChangedFcn = createCallbackFcn(app, @Category2ValueChanged, true);
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = 'Правила';
            app.Label_5 = uilabel(app.Tab_3);
            app.Label_5.Text = 'Категории';
            app.NamesCats = uilistbox(app.Tab_3);
            app.NamesCats.Items = {};
            app.NamesCats.Multiselect = 'on';
            app.NamesCats.ValueChangedFcn = createCallbackFcn(app, @NamesCatsValueChanged, true);
            app.NamesCats.Value = {};
            app.Label_6 = uilabel(app.Tab_3);
            app.Label_6.Text = 'Правило';
            app.RuleType = uidropdown(app.Tab_3);
            app.RuleType.Items = {'Переименовать категорию (RC)', 'Изменить название (RN)', 'Сменить категорию (SC)'};
            app.RuleType.ItemsData = {'RC', 'RN', 'SC'};
            app.RuleType.ValueChangedFcn = createCallbackFcn(app, @RuleTypeValueChanged, true);
            app.RuleType.Value = 'RC';
            app.PreviewLabel = uilabel(app.Tab_3);
            app.PreviewLabel.Enable = 'off';
            app.PreviewLabel.Text = 'Preview';
            app.Preview = uilistbox(app.Tab_3);
            app.Preview.Items = {};
            app.Preview.Enable = 'off';
            app.Preview.Value = {};
            app.Label_8 = uilabel(app.Tab_3);
            app.Label_8.Text = {'Новое'; 'значение'};
            app.NewValue = uidropdown(app.Tab_3);
            app.NewValue.Items = {};
            app.NewValue.Editable = 'on';
            app.NewValue.Value = {};
            app.Rules = uitable(app.Tab_3);
            app.Rules.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.Rules.RowName = {};
            app.Rules.CellSelectionCallback = createCallbackFcn(app, @RulesCellSelection, true);
            app.Apply = uibutton(app.Tab_3, 'push');
            app.Apply.ButtonPushedFcn = createCallbackFcn(app, @ApplyButtonPushed, true);
            app.Apply.Text = '>';
            app.Tab_7 = uitab(app.TabGroup);
            app.Tab_7.Title = 'Настройки';
            app.Panel = uipanel(app.Tab_7);
            app.Panel.Title = 'Формат файла';
            app.Label_19 = uilabel(app.Panel);
            app.Label_19.Text = 'Столбцы';
            app.Label_13 = uilabel(app.Panel);
            app.Label_13.Text = 'Формат даты';
            app.DateFormat = uieditfield(app.Panel, 'text');
            app.DateFormat.Value = 'dd.MM.uuuu HH:mm:ss';
            app.Label_15 = uilabel(app.Panel);
            app.Label_15.Text = 'Дата';
            app.ColDate = uieditfield(app.Panel, 'numeric');
            app.ColDate.Limits = [1 Inf];
            app.ColDate.RoundFractionalValues = 'on';
            app.ColDate.ValueDisplayFormat = '%11g';
            app.ColDate.Value = 1;
            app.Label_16 = uilabel(app.Panel);
            app.Label_16.Text = 'Сумма';
            app.ColSum = uieditfield(app.Panel, 'numeric');
            app.ColSum.Limits = [1 Inf];
            app.ColSum.RoundFractionalValues = 'on';
            app.ColSum.ValueDisplayFormat = '%11g';
            app.ColSum.Value = 7;
            app.Label_17 = uilabel(app.Panel);
            app.Label_17.Text = 'Категория';
            app.ColCat = uieditfield(app.Panel, 'numeric');
            app.ColCat.Limits = [1 Inf];
            app.ColCat.RoundFractionalValues = 'on';
            app.ColCat.ValueDisplayFormat = '%11g';
            app.ColCat.Value = 10;
            app.Label_18 = uilabel(app.Panel);
            app.Label_18.Text = 'Название';
            app.ColName = uieditfield(app.Panel, 'numeric');
            app.ColName.Limits = [1 Inf];
            app.ColName.RoundFractionalValues = 'on';
            app.ColName.ValueDisplayFormat = '%11g';
            app.ColName.Value = 12;
            app.Label_20 = uilabel(app.Panel);
            app.Label_20.Enable = 'off';
            app.Label_20.Text = 'Загрузить для';
            app.LoadFormat = uidropdown(app.Panel);
            app.LoadFormat.Items = {'Тинькофф'};
            app.LoadFormat.Enable = 'off';
            app.LoadFormat.Value = 'Тинькофф';
            app.Label_22 = uilabel(app.Panel);
            app.Label_22.Text = 'Карта';
            app.ColCard = uieditfield(app.Panel, 'numeric');
            app.ColCard.Limits = [1 Inf];
            app.ColCard.RoundFractionalValues = 'on';
            app.ColCard.ValueDisplayFormat = '%11g';
            app.ColCard.Value = 3;
            app.Label_23 = uilabel(app.Panel);
            app.Label_23.Text = 'Статус';
            app.ColStatus = uieditfield(app.Panel, 'numeric');
            app.ColStatus.Limits = [1 Inf];
            app.ColStatus.RoundFractionalValues = 'on';
            app.ColStatus.ValueDisplayFormat = '%11g';
            app.ColStatus.Value = 4;
            app.Panel_2 = uipanel(app.Tab_7);
            app.Panel_2.Title = '      Автозагрузка при старте';
            app.Label_12 = uilabel(app.Panel_2);
            app.Label_12.Text = 'Хранилище данных';
            app.DataPath = uieditfield(app.Panel_2, 'text');
            app.DataPath.Value = 'D:/downloads';
            app.Label_14 = uilabel(app.Panel_2);
            app.Label_14.Text = 'Маска имени файла';
            app.FileMask = uieditfield(app.Panel_2, 'text');
            app.FileMask.Value = 'operations*.xls';
            app.AutoLoad = uicheckbox(app.Tab_7);
            app.AutoLoad.Tooltip = {'Загрузка последнего файла из хранилища при старте'};
            app.AutoLoad.Text = '';
            app.AutoLoad.Value = true;
            % Show the figure after all components are created
            app.FinancialAnalyzerUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FinApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.FinancialAnalyzerUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.FinancialAnalyzerUIFigure)
        end
    end
end